classdef urban_micro_climate_courier_delay_prediction_system < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RiskLevelLabel                 matlab.ui.control.Label
        PredictedDeliveryTimeLabel     matlab.ui.control.Label
        UrbanMicroClimateCourierDelayPredictionSystemLabel  matlab.ui.control.Label
        VisualizationPanel             matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        OperationalInputsPanel         matlab.ui.container.Panel
        TemperatureEditField           matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel      matlab.ui.control.Label
        TemperatureLabel               matlab.ui.control.Label
        TrafficLevelDropDown           matlab.ui.control.DropDown
        TrafficLevelDropDownLabel      matlab.ui.control.Label
        ExperienceinputEditField       matlab.ui.control.NumericEditField
        ExperienceinputEditFieldLabel  matlab.ui.control.Label
        ExperienceLabel                matlab.ui.control.Label
        PredictButton                  matlab.ui.control.Button
        TrafficLabel                   matlab.ui.control.Label
        DistanceinputEditField         matlab.ui.control.NumericEditField
        DistanceinputEditFieldLabel    matlab.ui.control.Label
        WindSpeedinputEditField        matlab.ui.control.NumericEditField
        WindSpeedinputEditFieldLabel   matlab.ui.control.Label
        RainfallinputEditField         matlab.ui.control.NumericEditField
        RainfallinputEditFieldLabel    matlab.ui.control.Label
        WindSpeedLabel                 matlab.ui.control.Label
        RainfallLabel                  matlab.ui.control.Label
        DistancekmLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        lm
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
           
            % Load dataset

            data = readtable("Food_Delivery_Times.csv");

            %remove missing values
            data = rmmissing(data);

            % rename variables
            data.Properties.VariableNames{'Delivery_Time_min'} = 'DeliveryTime';
            data.Properties.VariableNames{'Preparation_Time_min'} = 'PrepTime';
            data.Properties.VariableNames{'Courier_Experience_yrs'} = 'Experience';

            % Generate simulated weather variable
            rng(42);
            N = height(data);
            data.Precipitation = abs(randn(N,1)*3);
            data.WindSpeed = abs(randn(N,1)*10 + 10);
            data.Temperature  = randn(N,1)*8 + 20;

            % Convert traffic to numeric
            trafficNum = zeros(N,1);

            for i = 1:N
                switch string(data.Traffic_Level(i))
                    case "Low"
                        trafficNum(i) = 1;
                    case "Medium"
                        trafficNum(i) = 2;
                    case "High"
                        trafficNum(i) = 3;
                end
            end

            data.TrafficNum = trafficNum;

            % Train regression model

            app.lm = fitlm(data,...
                'DeliveryTime ~ Distance_km + PrepTime + Experience + Precipitation + WindSpeed + Temperature + TrafficNum');
            
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            distance = app.DistanceinputEditField.Value;
            rain = app.RainfallinputEditField.Value;
            wind = app.WindSpeedinputEditField.Value;
            temp = app.TemperatureEditField.Value;
            experience = app.ExperienceinputEditField.Value;
            traffic = app.TrafficLevelDropDown.Value;

            % Traffic Encoding
            switch traffic
                case 'Low'
                    trafficNum = 1;
                case 'Medium'
                    trafficNum = 2;
                case 'High'
                    trafficNum = 3;
            end
            % Fixed prep time
            prep = 15;
            % create input table
            newData = table( ...
                distance, ...
                prep, ...
                experience, ...
                rain, ...
                wind, ...
                temp, ...
                trafficNum, ...
                'VariableNames', ...
                {'Distance_km','PrepTime','Experience', ...
                'Precipitation','WindSpeed', ...
                'Temperature','TrafficNum'});
            % Predict

            predicted = predict(app.lm,newData);

            % Show Prediction

            app.PredictedDeliveryTimeLabel.Text = ...
                sprintf('Predicted Delivery Time: %.2f min', predicted);
            % Risk Level
            if predicted > 70
                risk = 'High Delay RISK';
            elseif predicted > 50
                risk = 'MODERATE DELAY';
            else
                risk = 'LOW DELAY';
            end
            app.RiskLevelLabel.Text = ...
                ['Risk Level: ', risk];
            %Visualization
            categories = categorical({'Distance','Rain','Wind','Traffic'});
            values = [distance rain wind trafficNum];
            bar(app.UIAxes,categories,values)
            title(app.UIAxes,'Operational Conditions');
            ylabel(app.UIAxes,'Input Magnitude');
            grid(app.UIAxes,"on");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OperationalInputsPanel = uipanel(app.UIFigure);
            app.OperationalInputsPanel.Title = 'Operational Inputs';
            app.DistancekmLabel = uilabel(app.OperationalInputsPanel);
            app.DistancekmLabel.Text = 'Distance(km)';
            app.RainfallLabel = uilabel(app.OperationalInputsPanel);
            app.RainfallLabel.Text = 'Rainfall';
            app.WindSpeedLabel = uilabel(app.OperationalInputsPanel);
            app.WindSpeedLabel.Text = 'WindSpeed';
            app.RainfallinputEditFieldLabel = uilabel(app.OperationalInputsPanel);
            app.RainfallinputEditFieldLabel.Text = 'Rainfall input';
            app.RainfallinputEditField = uieditfield(app.OperationalInputsPanel, 'numeric');
            app.RainfallinputEditField.Value = 1;
            app.WindSpeedinputEditFieldLabel = uilabel(app.OperationalInputsPanel);
            app.WindSpeedinputEditFieldLabel.Text = 'WindSpeed input';
            app.WindSpeedinputEditField = uieditfield(app.OperationalInputsPanel, 'numeric');
            app.WindSpeedinputEditField.Value = 111;
            app.DistanceinputEditFieldLabel = uilabel(app.OperationalInputsPanel);
            app.DistanceinputEditFieldLabel.Text = 'Distance input';
            app.DistanceinputEditField = uieditfield(app.OperationalInputsPanel, 'numeric');
            app.DistanceinputEditField.Value = 10;
            app.TrafficLabel = uilabel(app.OperationalInputsPanel);
            app.TrafficLabel.Text = 'Traffic';
            app.PredictButton = uibutton(app.OperationalInputsPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.ExperienceLabel = uilabel(app.OperationalInputsPanel);
            app.ExperienceLabel.Text = 'Experience';
            app.ExperienceinputEditFieldLabel = uilabel(app.OperationalInputsPanel);
            app.ExperienceinputEditFieldLabel.Text = 'Experience input';
            app.ExperienceinputEditField = uieditfield(app.OperationalInputsPanel, 'numeric');
            app.ExperienceinputEditField.Value = 1;
            app.TrafficLevelDropDownLabel = uilabel(app.OperationalInputsPanel);
            app.TrafficLevelDropDownLabel.Text = 'Traffic Level';
            app.TrafficLevelDropDown = uidropdown(app.OperationalInputsPanel);
            app.TrafficLevelDropDown.Items = {'Low', 'Medium', 'High'};
            app.TrafficLevelDropDown.Value = 'Medium';
            app.TemperatureLabel = uilabel(app.OperationalInputsPanel);
            app.TemperatureLabel.Text = 'Temperature';
            app.TemperatureEditFieldLabel = uilabel(app.OperationalInputsPanel);
            app.TemperatureEditFieldLabel.Text = 'Temperature ';
            app.TemperatureEditField = uieditfield(app.OperationalInputsPanel, 'numeric');
            app.TemperatureEditField.Value = 11;
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.Title = 'Visualization Panel';
            app.UIAxes = uiaxes(app.VisualizationPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UrbanMicroClimateCourierDelayPredictionSystemLabel = uilabel(app.UIFigure);
            app.UrbanMicroClimateCourierDelayPredictionSystemLabel.Text = 'Urban Micro-Climate Courier Delay Prediction System';
            app.PredictedDeliveryTimeLabel = uilabel(app.UIFigure);
            app.PredictedDeliveryTimeLabel.Text = 'Predicted Delivery Time';
            app.RiskLevelLabel = uilabel(app.UIFigure);
            app.RiskLevelLabel.Text = 'Risk Level';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = urban_micro_climate_courier_delay_prediction_system

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end